classdef rsa_simple_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RSASimpleGUIUIFigure     matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        PointmetoyourstudydirectoryButton  matlab.ui.control.Button
        SelectyourMaskROIButton  matlab.ui.control.Button
        RunAnalysisButton        matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        StudyPathEditField       matlab.ui.control.EditField
        Label                    matlab.ui.control.Label
        MaskEditField            matlab.ui.control.EditField
        MaskEditFieldLabel       matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        StatusLabel              matlab.ui.control.Label
        StudyPathEditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PointmetoyourstudydirectoryButton
        function PointmetoyourstudydirectoryButtonPushed(app, event)
            % Open the folder selection dialog
            selectedFolder = uigetdir();

            % uigetdir returns 0 if the user clicks "Cancel"
            if selectedFolder ~= 0
                % Update the text box with the chosen path
                app.StudyPathEditField.Value = selectedFolder;
            end
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            % Grab the folder path from the UI
            studytargetFolder = app.StudyPathEditField.Value;

            % Grab the maskname from the UI
            masknameandpath = app.MaskEditField.Value;


            % Safety check: Ensure the user actually selected
            % valid inputs
            %study folder:
            if isempty(studytargetFolder) || ~isfolder(studytargetFolder)
                uialert(app.RSASimpleGUIUIFigure, 'Please browse and select a valid folder first.', 'Invalid Path');
                return; % Stop execution here
            end

            %mask specified:
            if isempty(masknameandpath)
                uialert(app.RSASimpleGUIUIFigure, 'Please browse and select a valid nifti format Mask/ROI first.','mask not specified');
                return; % Stop execution here
            end

            %extract the mask filename for naming output of the downstream function
            % fileparts returns 3 things: the path, the name, and the extension.
            % We use the tilde (~) to tell MATLAB to ignore the path and extension.
            [~, justTheName, ~] = fileparts(masknameandpath);

            % CONVERT TO STRING:
            % Convert that specific extracted name into a character string
            % if it isn't already
            MaskNameString = char(justTheName);


            % Update UI to show it is working
            app.StatusLabel.Text = 'Processing data...';
            drawnow; % Forces the UI to update immediately

            % Pass the path into your external complex function
            try
                rsa_simple(studytargetFolder,masknameandpath,MaskNameString);
                app.StatusLabel.Text = 'Analysis Complete!';
            catch ME
                uialert(app.RSASimpleGUIUIFigure, ['Error during execution: ', ME.message], 'Function Error');
                app.StatusLabel.Text = 'Error occurred.';
            end
        end

        % Button pushed function: SelectyourMaskROIButton
        function SelectyourMaskROIButtonPushed(app, event)
            % Open the file selection dialog (you can filter by file type, e.g., '*.nii' for nifti images)
            [file, path] = uigetfile('*.nii*', 'Select a nifti format mask');

            % uigetfile returns 0 if the user clicks "Cancel"
            if ischar(file)
                % Combine the path and filename, then update the text box
                fullPath = fullfile(path, file);
                app.MaskEditField.Value = fullPath;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RSASimpleGUIUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RSASimpleGUIUIFigure = uifigure('Visible', 'off');
            app.RSASimpleGUIUIFigure.Name = 'RSA Simple GUI';
            app.RSASimpleGUIUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.RSASimpleGUIUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RunAnalysisButton = uibutton(app.LeftPanel, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.SelectyourMaskROIButton = uibutton(app.LeftPanel, 'push');
            app.SelectyourMaskROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectyourMaskROIButtonPushed, true);
            app.SelectyourMaskROIButton.Text = 'Select your Mask/ROI';
            app.PointmetoyourstudydirectoryButton = uibutton(app.LeftPanel, 'push');
            app.PointmetoyourstudydirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PointmetoyourstudydirectoryButtonPushed, true);
            app.PointmetoyourstudydirectoryButton.Text = 'Point me to your study directory';
            app.RightPanel = uipanel(app.GridLayout);
            app.StudyPathEditFieldLabel = uilabel(app.RightPanel);
            app.StudyPathEditFieldLabel.Text = 'Study Path';
            app.StatusLabel = uilabel(app.RightPanel);
            app.StatusLabel.Text = 'Status:';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = 'e.g., bilatall or hippocampus_left or...   |   needs to be nifti (.nii) format!';
            app.MaskEditFieldLabel = uilabel(app.RightPanel);
            app.MaskEditFieldLabel.Text = 'Mask ';
            app.MaskEditField = uieditfield(app.RightPanel, 'text');
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = 'This assumes your datasets (e.g., each subjects'' MRI data) are subdirectories of this study folder';
            app.StudyPathEditField = uieditfield(app.RightPanel, 'text');
            % Show the figure after all components are created
            app.RSASimpleGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rsa_simple_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RSASimpleGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RSASimpleGUIUIFigure)
        end
    end
end